classdef base_station < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DropDownSig1            matlab.ui.control.DropDown
        TextArea                matlab.ui.control.TextArea
        xmlfileEditField        matlab.ui.control.EditField
        xmlfileEditFieldLabel   matlab.ui.control.Label
        DISCONNECTButton        matlab.ui.control.Button
        CONNECTButton           matlab.ui.control.Button
        BaudRateEditField       matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel  matlab.ui.control.Label
        COMPortEditField        matlab.ui.control.EditField
        COMPortEditFieldLabel   matlab.ui.control.Label
        StatsAxes               matlab.ui.control.UIAxes
        UIAxesSig               matlab.ui.control.UIAxes
    end

    properties (Access = public)
        serial
        dialect
        mavlink
        timer_rx
        timer_heartbeat

        pool
        queue_to_worker
        queue_to_gui
        queue_debug
        worker

        timer_disp
        tx_bytes_accum = uint64(0);
        stats = struct( ...
            'status', 0, ...                   % reserved
            'radio', struct( ...               % latest RADIO_STATUS snapshot
                'rssi', NaN, ...
                'remrssi', NaN, ...
                'noise', NaN, ...
                'remnoise', NaN, ...
                'txbuf', NaN, ...
                'rxerrors', NaN, ...
                'fixed', NaN, ...
                'snr', NaN, ...                % computed: rssi - noise
                'rem_snr', NaN, ...            % computed: remrssi - remnoise
                'ts', NaT ...                  % timestamp of last update
            ), ...
            'uart', struct( ...
                'baud', NaN, ...        % current baudrate
                'bits_per_byte', NaN, ...% start + data + stop (+parity)
                'util_rx', NaN, ...     % %
                'util_tx', NaN, ...     % %
                'util_total', NaN, ...  % %
                'rx_speed', NaN, ...      % bytes/s
                'tx_speed', NaN, ...      % bytes/s
                'active', false ...
            ) ...
        );
        statsPlot struct   % cały „moduł” rysowania SNR

        unique_signals_received_struct struct = struct( ...
            'sigKeysMap', containers.Map('KeyType','char','ValueType','logical'), ... % unikalne frame_signal
            'sigList', strings(0,1), ...                                              % lista "FRAME_SIGNAL"
            'frameSeenMap', containers.Map('KeyType','char','ValueType','logical') ...% przyspieszenie: widzieliśmy już ten frame?
        );

    end
            
    methods (Access = private)
        function initStatsPlot(app)
            S.ax         = app.StatsAxes;
            S.minWindowS = 60;     % minimalna szerokość osi czasu w sekundach
            S.maxSamples = 600;    % <-- ILE OSTATNICH PRÓBEK PRZECHOWUJEMY (stały bufor)
                                   % np. 300 próbek przy ~1 Hz ≈ 5 min danych
            % --- bufor kołowy (prealokacja, ZERO dynamicznych alokacji w update) ---
            S.bufT     = NaN(1, S.maxSamples);   % czas względny [s], ujemny ... 0
            S.bufSNR   = NaN(1, S.maxSamples);
            S.bufRmSNR = NaN(1, S.maxSamples);
            S.head     = 0;                      % indeks ostatnio wpisanego (1..maxSamples)
            S.full     = false;                  % czy bufor już „okrążył”
            S.lastNow  = datetime('now');        % do liczenia dt między wywołaniami
            ax = S.ax; hold(ax, 'on');
            ax.YLim = [0 100];
            % Wyłącz interakcje
            try
                disableDefaultInteractivity(ax);
                ax.Toolbar.Visible = 'off';
            catch
            end
            % --- Kolorowe tło wg SNR (blade) ---
            bgRanges = [
                 0   5    [1.0 0.4 0.4];  % zły
                 5   10   [1.0 0.7 0.4];  % kiepski
                10   20   [1.0 1.0 0.4];  % ok
                20  100   [0.7 1.0 0.7];  % dobry
            ];
            S.bgPatches = gobjects(size(bgRanges,1),1);
            for i = 1:size(bgRanges,1)
                y1 = bgRanges(i,1);
                y2 = bgRanges(i,2);
                c  = bgRanges(i,3:end);
                S.bgPatches(i) = patch(ax, ...
                    'XData', [-S.minWindowS 0 0 -S.minWindowS], ...
                    'YData', [y1 y1 y2 y2], ...
                    'FaceColor', c, ...
                    'FaceAlpha', 0.2, ...
                    'EdgeColor', 'none', ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none', ...
                    'HandleVisibility', 'off');  % << nie trafia do legendy
            end
            % Linie SNR
            S.hSNR    = plot(ax, NaN, NaN, '-', 'LineWidth', 1, 'DisplayName','SNR [dB]');
            S.hRemSNR = plot(ax, NaN, NaN, '-', 'LineWidth', 1, 'DisplayName','rmSNR [dB]');
            % Oś i styl
            ylabel(ax, 'SNR [dB]');
            ax.XLabel.String = '';
            ax.YLabel.String = '';
            box(ax, 'off');
            ax.XGrid = 'off'; ax.YGrid = 'off';
            ax.XLim = [-S.minWindowS 0];
            legend(ax, 'Location','northwest', 'Box','off', 'AutoUpdate','off');
            app.statsPlot = S;
        end
        function updateStatsPlot(app, SNR, rmSNR)
            S = app.statsPlot;
            if isempty(S), return; end
            % --- przesunięcie czasu względnego o -dt dla CAŁEGO bufora ---
            tnow = datetime('now');
            dt = seconds(tnow - S.lastNow);
            S.lastNow = tnow;
            % Uaktualnij tylko istniejące elementy (bez NaNów)
            if S.full
                idx = 1:S.maxSamples;
            else
                if S.head == 0
                    idx = [];   % jeszcze nic nie mamy
                else
                    idx = 1:S.head;
                end
            end
            if ~isempty(idx)
                S.bufT(idx) = S.bufT(idx) - dt;   % wszystko „płynie” w lewo o dt
            end
            % --- wstaw nową próbkę na głowę bufora ---
            S.head = S.head + 1;
            if S.head > S.maxSamples
                S.head = 1;
                S.full = true;
            end
            S.bufT(S.head)     = 0;      % „teraz” po prawej
            S.bufSNR(S.head)   = SNR;
            S.bufRmSNR(S.head) = rmSNR;
            % --- z jakiego zakresu rysujemy? ---
            % oś czasu dostosowana do rzeczywistego zasięgu czasów w buforze,
            % ale MINIMALNIE S.minWindowS
            if S.full
                allIdx = 1:S.maxSamples;
            else
                allIdx = 1:S.head;
            end
            % tylko te próbki, które mają X<=0 (czas względny) i Y nie-NaN
            valid1 = allIdx(~isnan(S.bufSNR(allIdx))   & ~isnan(S.bufT(allIdx))   & S.bufT(allIdx) <= 0);
            valid2 = allIdx(~isnan(S.bufRmSNR(allIdx)) & ~isnan(S.bufT(allIdx))   & S.bufT(allIdx) <= 0);
            % bieżący rozciąg czasowy danych
            minRelT = 0;
            if ~isempty(allIdx)
                % najmniejszy (najbardziej ujemny) czas w dostępnych próbkach
                haveT = S.bufT(allIdx);
                haveT = haveT(~isnan(haveT));
                if ~isempty(haveT)
                    minRelT = min(haveT);   % np. -133.2
                end
            end
            axisWidth = max(S.minWindowS, -minRelT);   % [s], co najmniej 60 s
            % --- aktualizacja szerokości tła do bieżącej osi czasu ---
            if isfield(S, 'bgPatches') && ~isempty(S.bgPatches)
                newX = [-axisWidth 0 0 -axisWidth];
                for k = 1:numel(S.bgPatches)
                    if isgraphics(S.bgPatches(k))
                        set(S.bgPatches(k), 'XData', newX);
                    end
                end
            end
            % klip do aktualnego okna (żeby nie rysować „dawnych” próbek)
            inwin = @(ids) ids(S.bufT(ids) >= -axisWidth & S.bufT(ids) <= 0);
            ids1 = inwin(valid1);
            ids2 = inwin(valid2);
            % musimy wyświetlić w KOLEJNOŚCI rosnącej po X
            [x1, order1] = sort(S.bufT(ids1));
            y1 = S.bufSNR(ids1); y1 = y1(order1);
            [x2, order2] = sort(S.bufT(ids2));
            y2 = S.bufRmSNR(ids2); y2 = y2(order2);
            % aktualizacja linii (bez replotów)
            set(S.hSNR,    'XData', x1, 'YData', y1);
            set(S.hRemSNR, 'XData', x2, 'YData', y2);
            % stała prawa krawędź w 0, lewa = -axisWidth (≥ 60 s)
            ax = S.ax;
            if isvalid(ax)
                ax.XLim = [-axisWidth 0];
            end
            drawnow limitrate;
            app.statsPlot = S;
        end


        function bpb = get_bits_per_byte(app)
            % 1 start + DataBits + StopBits + (parity ? 1 : 0)
            try
                dataBits = app.serial.DataBits;      % zwykle 8
                stopBits = app.serial.StopBits;      % 1, 1.5, 2 (zaokrąglimy w górę)
                parityOn = ~strcmpi(app.serial.Parity, "none");
                bpb = 1 + double(dataBits) + ceil(double(stopBits)) + double(parityOn);
            catch
                bpb = 10; % domyślnie 8N1
            end
        end
        function refresh_uart_params(app, baud)
            app.stats.uart.baud = double(baud);
            app.stats.uart.bits_per_byte = app.get_bits_per_byte();
        end
        function writeSerial(app, data, precision)
            if nargin < 3, precision = "uint8"; end
            write(app.serial, data, precision);
            % zlicz ile bajtów poszło
            app.tx_bytes_accum = app.tx_bytes_accum + uint64(numel(typecast(cast(data, 'uint8'), 'uint8')));
        end

        function sendHeartbeat(app)
            try
                hbMsg = createmsg(app.dialect, "HEARTBEAT");
                % GCS identity and basic mode/state for MAVLink v1.0
                hbMsg.Payload.type = uint8(6);              % MAV_TYPE_GCS (6)
                hbMsg.Payload.autopilot = uint8(8);         % MAV_AUTOPILOT_INVALID (8)
                hbMsg.Payload.base_mode = uint8(0);         % No base mode flags set
                hbMsg.Payload.system_status = uint8(0);     % MAV_STATE_UNINIT (0)
                hbMsg.Payload.custom_mode = uint32(0);      % No custom mode
                % hbMsg.Payload.mavlink_version = uint8(3); % Not writable by user
                hbMsg.SystemID = uint8(255);                % Typical GCS system ID
                hbMsg.ComponentID = uint8(190);             % Typical GCS component ID
                buffer = serializemsg(app.mavlink, hbMsg);
                app.writeSerial(buffer, "uint8");
            catch e
                disp("Heartbeat send error: " + e.message)
            end
        end

        function rx(app)
            % RX read
            data = uint8([]);
            if app.serial.NumBytesAvailable > 0
                data = read(app.serial, app.serial.NumBytesAvailable, "uint8");
                data = uint8(data);
                send(app.queue_to_worker, data);
            end
        
            % Sample period = timer_rx.Period
            Ts = app.timer_rx.Period;
        
            % RX speed (bytes/s)
            rx_bytes = length(data);
            app.stats.uart.rx_speed = app.avg_speed_window(rx_bytes, Ts, "rx");
        
            % TX speed (bytes/s)
            tx_bytes = double(app.tx_bytes_accum);
            app.tx_bytes_accum = uint64(0); % reset po próbkowaniu
            app.stats.uart.tx_speed = app.avg_speed_window(tx_bytes, Ts, "tx");
        
            % Port utilization
            baud = app.stats.uart.baud;
            bpb  = app.stats.uart.bits_per_byte;
            if ~(isnan(baud) || baud <= 0)
                % bits/s użyte: bytes/s * bits/byte
                util_rx = (app.stats.uart.rx_speed * bpb) / baud;
                util_tx = (app.stats.uart.tx_speed * bpb) / baud;
                app.stats.uart.util_rx    = 100 * util_rx;
                app.stats.uart.util_tx    = 100 * util_tx;
                app.stats.uart.util_total = 100 * (util_rx + util_tx); % przy half‑duplex to i tak max~100
            else
                app.stats.uart.util_rx = NaN;
                app.stats.uart.util_tx = NaN;
                app.stats.uart.util_total = NaN;
            end
        end
        
        function speed = avg_speed_window(app, bytes, period, tag)
            % Keeps a per-tag sliding window average of byte rate.
            % 'tag' identifies separate buffers for RX and TX.
            % Uses a 1-second window split into 1/period samples.
            window_time = 1; % [s]
            samples = max(1, round(window_time / period));
            persistent map;
            if isempty(map), map = containers.Map(); end
            if ~isKey(map, tag)
                map(tag) = struct( ...
                    'buffer', zeros(1, samples), ...
                    'index', 0, ...
                    'count', 0, ...
                    'sum_bytes', 0, ...
                    'samples', samples);
            end
            st = map(tag);
            % Realloc if period changed => samples changed
            if st.samples ~= samples
                st.buffer = zeros(1, samples);
                st.index = 0; st.count = 0; st.sum_bytes = 0; st.samples = samples;
            end
            st.index = mod(st.index, samples) + 1;
            st.sum_bytes = st.sum_bytes - st.buffer(st.index) + bytes;
            st.buffer(st.index) = bytes;
            st.count = min(st.count + 1, samples);
            map(tag) = st;
            speed = st.sum_bytes / (st.count * period); % bytes/s
        end


% --- MAIN DISPLAY UPDATE ---
function disp_update_stats(app)
    % lokalny helper tylko na potrzeby tego bloku:
    function out = ternNum(val, fmt)
        if isnan(val)
            out = "--";
        else
            out = sprintf(fmt, val);
        end
    end
    function out = ternNumFlag(flag, onText, offText)
        if flag
            out = onText;
        else
            out = offText;
        end
    end
    function s = staleSuffix(ts)
        if ~isnat(ts)
            age = seconds(datetime('now') - ts);
            s = sprintf('(age: %.1fs)', age);
        else
            s = '';
        end
    end
    % --- Uart utilization ---
    u = app.stats.uart;
    lines_util = {
        sprintf("Serial port %s", ternNumFlag(u.active, 'active', 'inactive'))
        sprintf(" RX speed:\t%s",      ternNum(u.rx_speed,   "%.1f B/s"))
        sprintf(" TX speed:\t%s",      ternNum(u.tx_speed,   "%.1f B/s"))
        sprintf(" RX load:\t%s",      ternNum(u.util_rx,    "%.1f%%"))
        sprintf(" TX load:\t%s",     ternNum(u.util_tx,    "%.1f%%"))
        sprintf(" Total load:\t%s",    ternNum(u.util_total, "%.1f%%"))
        sprintf(" Baud rate:\t%s",     ternNum(u.baud,       "%g"))
        sprintf(" Bits per byte: %s", ternNum(u.bits_per_byte, "%g"))
    };
    % --- RADIO STATUS ---
    r = app.stats.radio;
    lines_radio = {
        sprintf("RADIO LINK %s", staleSuffix(r.ts))
        sprintf(" RSSI (local):    %s",   ternNum(r.rssi,  "%.0f dBm"))
        sprintf(" RSSI (remote):   %s",    ternNum(r.remrssi,"%.0f dBm"))
        sprintf(" Noise (local):   %s",  ternNum(r.noise,  "%.0f dBm"))
        sprintf(" Noise (remote):  %s",   ternNum(r.remnoise,"%.0f dBm"))
        sprintf(" SNR (local):     %s",    ternNum(r.snr,    "%.0f dB"))
        sprintf(" SNR (remote):    %s",     ternNum(r.rem_snr,"%.0f dB"))
        sprintf(" TX buffer free:  %s",   ternNum(r.txbuf,  "%.0f%%"))
        sprintf(" RX errors (cum): %s",  ternNum(r.rxerrors,"%.0f"))
        sprintf(" Fixed (FEC, cum):%s", ternNum(r.fixed,  "%.0f"))
    };
    % --- Compose and show ---
    app.TextArea.Value = [
        cellstr(lines_util);
        cellstr(lines_radio)
    ];
end
function resetStats(app)
    % --- RADIO ---
    app.stats.radio.rssi     = NaN;
    app.stats.radio.remrssi  = NaN;
    app.stats.radio.noise    = NaN;
    app.stats.radio.remnoise = NaN;
    app.stats.radio.txbuf    = NaN;
    app.stats.radio.rxerrors = NaN;
    app.stats.radio.fixed    = NaN;
    app.stats.radio.snr      = NaN;
    app.stats.radio.rem_snr  = NaN;
    % --- UART ---
    app.stats.uart.baud          = NaN;
    app.stats.uart.bits_per_byte = NaN;
    app.stats.uart.util_rx       = NaN;
    app.stats.uart.util_tx       = NaN;
    app.stats.uart.util_total    = NaN;
    app.stats.uart.rx_speed      = NaN;
    app.stats.uart.tx_speed      = NaN;
end

function added = updateUniqueSignals(app, frame_name, signal_names)
% Adds new FRAME_SIGNAL pairs if not seen before.
% Returns true if any new entry was added.
%
% Changes vs previous:
%  - Early exit when frame already seen (frameSeenMap)
%  - Keys use '_' instead of '.'
%  - Dropdown always has leading 'None' and defaults to it

    added = false;
    if isempty(signal_names) || isempty(frame_name), gotoUpdateDropdown(); return; end

    % Ensure cell array of chars
    if isstring(signal_names)
        sigs = cellstr(signal_names);
    else
        sigs = signal_names; % cellstr expected
    end
    f = char(frame_name);

    % === EARLY-EXIT: jeśli frame już widziany, nie rób nic więcej ===
    frameSeen = app.unique_signals_received_struct.frameSeenMap;
    if isKey(frameSeen, f)
        % nawet jeśli nic nie doszło, dopilnuj "None" w dropdownie
        gotoUpdateDropdown();
        return;
    else
        frameSeen(f) = true; % zaznacz jako widziany
        app.unique_signals_received_struct.frameSeenMap = frameSeen;
    end

    % Normalny przebieg: dodaj pary FRAME_SIGNAL z separatorem '_'
    mapRef  = app.unique_signals_received_struct.sigKeysMap;
    listRef = app.unique_signals_received_struct.sigList;

    for k = 1:numel(sigs)
        key = [f '_' sigs{k}];  % <-- separator '_'
        if ~isKey(mapRef, key)
            mapRef(key) = true;
            listRef(end+1,1) = string(key);
            added = true;
        end
    end

    % Save back to struct
    app.unique_signals_received_struct.sigKeysMap = mapRef;
    app.unique_signals_received_struct.sigList    = listRef;

    % Zawsze dopilnuj dropdownu (z leading 'None')
    gotoUpdateDropdown();
    return;

    % ======== lokalna "etykieta" do wspólnej obsługi dropdownu ========
    function gotoUpdateDropdown()
        if isprop(app, 'DropDownSig1') && ~isempty(app.DropDownSig1)
            % Zbuduj Items: 'None' + unikalne sygnały
            curList = app.unique_signals_received_struct.sigList;
            items = [{'None'}; cellstr(curList)];
            app.DropDownSig1.Items = items;

            % Domyślnie 'None' jako Value (jeśli puste albo niepoprawne)
            if isempty(app.DropDownSig1.Value) || ~any(strcmp(app.DropDownSig1.Value, items))
                app.DropDownSig1.Value = 'None';
            end
        end
    end
end

                
        function onMsgs(app, msgs)
            % Handle parsed messages arriving from the worker.
            function v = toDbl(s, name)
                if isfield(s, name) && ~isempty(s.(name))
                    v = double(s.(name));
                else
                    v = NaN;
                end
            end
            for i = 1:numel(msgs)
                s = msgs{i};
                if ~isstruct(s), continue; end
                switch s.msgid
                    case 0 % HEARTBEAT
                        % Example fields: s.type, s.base_mode, s.system_status
                    case 109 % RADIO_STATUS
                        % Store full snapshot and compute SNRs
                        app.stats.radio.rssi     = (toDbl(s, 'rssi') / 1.9 ) - 127; % dBm
                        app.stats.radio.remrssi  = (toDbl(s, 'remrssi') / 1.9 ) - 127; % dBm
                        app.stats.radio.noise    = (toDbl(s, 'noise') / 1.9 ) - 127; % dBm
                        app.stats.radio.remnoise = (toDbl(s, 'remnoise') / 1.9 ) - 127; % dBm
                        app.stats.radio.txbuf    = toDbl(s, 'txbuf');
                        app.stats.radio.rxerrors = toDbl(s, 'rxerrors');
                        app.stats.radio.fixed    = toDbl(s, 'fixed');
                        if ~isnan(app.stats.radio.rssi) && ~isnan(app.stats.radio.noise)
                            app.stats.radio.snr = app.stats.radio.rssi - app.stats.radio.noise;
                        else
                            app.stats.radio.snr = NaN;
                        end
                        if ~isnan(app.stats.radio.remrssi) && ~isnan(app.stats.radio.remnoise)
                            app.stats.radio.rem_snr = app.stats.radio.remrssi - app.stats.radio.remnoise;
                        else
                            app.stats.radio.rem_snr = NaN;
                        end
                        app.stats.radio.ts = datetime('now');
                        app.updateStatsPlot(app.stats.radio.snr, app.stats.radio.rem_snr);
                    case 200 % GENERIC_CAN_FRAME
                        % Extract the signal_name and frame_names
                        frame_name = s.decoded.frame;
                        signal_names = fieldnames(s.decoded.signals);
                        app.updateUniqueSignals(frame_name, signal_names);
                        
                    case 201 % DEBUG_FRAME

                    otherwise
                        
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timer_disp = timer('ExecutionMode', 'fixedSpacing', ...
                                      'Period', 1/10, ... % 10 hz
                                      'TimerFcn', @(~,~) app.disp_update_stats());
            start(app.timer_disp);

            app.initStatsPlot();

        end

        % Button pushed function: CONNECTButton
        function connectSerial(app, event)
            clc
            port = char(app.COMPortEditField.Value);
            baud = double(app.BaudRateEditField.Value);
            xmlPath = char(app.xmlfileEditField.Value);
            
            % 1) Dialect + mavlink
            try
                app.dialect = mavlinkdialect(xmlPath, 1);
                app.mavlink = mavlinkio(app.dialect);
                disp(['Dialect ', xmlPath, ' loaded']);
            catch e
                app.mavlink = [];
                uialert(app.UIFigure, ['Error loading dialect: ', e.message], 'MAVLink Error');
                return;
            end
    
            % 2) Serial
            try
                app.serial = serialport(port, baud);
                app.refresh_uart_params(baud);
                app.stats.uart.active = true;
                disp(['Connected to ', port, ' at ', num2str(baud), ' baud']);
            catch e
                app.stats.uart.active = false;
                uialert(app.UIFigure, ['Error connecting: ', e.message], 'Connection Error');
                return; % No port -> do not start nothig further
            end
    
            % 3) Pool/queues/worker
            try
                disp("Starting the thread worker...")
                if ~isempty(gcp('nocreate'))
                    delete(gcp('nocreate'))
                end
                app.pool = parpool('Processes',1);
                app.queue_to_worker = parallel.pool.PollableDataQueue(Destination="any");
                app.queue_to_gui = parallel.pool.DataQueue;
                app.queue_debug = parallel.pool.DataQueue;
        
                afterEach(app.queue_to_gui, @(msgs) app.onMsgs(msgs));
                afterEach(app.queue_debug, @(data) disp("@@" + string(data)));
        
                app.worker = parfeval(app.pool, @worker, 0, app.queue_to_worker, app.queue_to_gui, app.queue_debug);
                disp('Started a thread worker for heavy tasks');
            catch e
                uialert(app.UIFigure, ['Error starting a worker: ', e.message], 'Thread Error');
                return;
            end
    
            % 4) RX timer
            try
                app.timer_rx = timer('ExecutionMode', 'fixedSpacing', ...
                          'Period', 0.2, ...
                          'TimerFcn', @(~,~) app.rx());
                start(app.timer_rx);
            catch e
                uialert(app.UIFigure, ['Error with a timer: ', e.message], 'Timer Error');
            end
        
            % 5) Heartbeat timer
            try
                app.timer_heartbeat = timer('ExecutionMode', 'fixedSpacing', ...
                                             'Period', 1.0, ...
                                             'TimerFcn', @(~,~) app.sendHeartbeat());
                start(app.timer_heartbeat);
                disp("Heartbeat timer started");
            catch e
                uialert(app.UIFigure, ['Error starting heartbeat timer: ', e.message], 'Timer Error');
            end
        end

        % Button pushed function: DISCONNECTButton
        function disconnectSerial(app, event)

            % 1) Timers
            try
                stop(app.timer_rx)
                delete(app.timer_rx)
            catch e
                uialert(app.UIFigure, ['Error stoping timer: ', e.message], 'Thread Error');
            end
            try
                stop(app.timer_heartbeat);
                delete(app.timer_heartbeat);
                disp("Heartbeat timer stopped");
            catch e
                uialert(app.UIFigure, ['Error stoping timer: ', e.message], 'Thread Error');
            end
            
            % 2) Serial
            try
                if ~isempty(app.serial) && isvalid(app.serial)
                    delete(app.serial);
                end
                app.stats.uart.active = false;
                app.resetStats()
                disp(['Serial port ', char(app.COMPortEditField.Value), ' disconnected']);
            catch e
                app.stats.uart.active = false;
                app.resetStats()
                uialert(app.UIFigure, ['Error disconnecting: ', e.message], 'Connection Error');
            end
            
            % 3) Workers / pool
            try
                if ~isempty(app.worker) && isvalid(app.worker)
                    try cancel(app.worker); catch, end 
                    delete(app.worker);
                    app.worker = [];
                end
            catch e
                uialert(app.UIFigure, ['Error stopping worker: ', e.message], 'Thread Error');
            end
            try
                if ~isempty(gcp('nocreate'))
                    delete(gcp('nocreate'))
                end
                if ~isempty(app.pool)
                    delete(app.pool);
                    app.pool = [];
                end
                disp('Stopped a thread pool');
            catch e
                uialert(app.UIFigure, ['Error deleting pool: ', e.message], 'Thread Error');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSig = uiaxes(app.UIFigure);
            title(app.UIAxesSig, 'Title')
            xlabel(app.UIAxesSig, 'X')
            ylabel(app.UIAxesSig, 'Y')
            zlabel(app.UIAxesSig, 'Z')
            app.StatsAxes = uiaxes(app.UIFigure);
            app.COMPortEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.COMPortEditField.Placeholder = 'COM';
            app.COMPortEditField.Value = 'COM17';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.Limits = [0 Inf];
            app.BaudRateEditField.ValueDisplayFormat = '%d';
            app.BaudRateEditField.Value = 115200;
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @connectSerial, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectSerial, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.xmlfileEditFieldLabel = uilabel(app.UIFigure);
            app.xmlfileEditFieldLabel.Text = '.xml file';
            app.xmlfileEditField = uieditfield(app.UIFigure, 'text');
            app.xmlfileEditField.Placeholder = './mavmc.xml';
            app.xmlfileEditField.Value = '/mavmc.xml';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'Connection stats';
            app.DropDownSig1 = uidropdown(app.UIFigure);
            app.DropDownSig1.Items = {'None'};
            app.DropDownSig1.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = base_station

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end